classdef MechLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        p2_v_theta              matlab.ui.control.UIAxes
        p2dot_v_theta           matlab.ui.control.UIAxes
        p2ddot_v_theta          matlab.ui.control.UIAxes
        MechanismDropDownLabel  matlab.ui.control.Label
        MechanismDropDown       matlab.ui.control.DropDown
        a1EditFieldLabel        matlab.ui.control.Label
        a1Edit                  matlab.ui.control.NumericEditField
        VariablesLabel          matlab.ui.control.Label
        a2EditFieldLabel        matlab.ui.control.Label
        a2Edit                  matlab.ui.control.NumericEditField
        mmLabel                 matlab.ui.control.Label
        mmLabel_2               matlab.ui.control.Label
        q0Edit                  matlab.ui.control.NumericEditField
        AnglerangeLabel         matlab.ui.control.Label
        qfEdit                  matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        a1EditFieldLabel_2      matlab.ui.control.Label
        a1EditFieldLabel_3      matlab.ui.control.Label
        q_stepEdit              matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        a1EditFieldLabel_4      matlab.ui.control.Label
        aboutMe                 matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        AnalyseButton           matlab.ui.control.Button
        ExportP2dataButton      matlab.ui.control.Button
        omegaEditFieldLabel     matlab.ui.control.Label
        omegaEditField          matlab.ui.control.NumericEditField
        alphaEditFieldLabel     matlab.ui.control.Label
        alphaEditField          matlab.ui.control.NumericEditField
        speed_units             matlab.ui.control.DropDown
        acc_units               matlab.ui.control.DropDown
        AngularmotionLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        theta;
        p1;
        p2;
        p1dot;
        p2dot;
        p1ddot;
        p2ddot;
    end
    
    methods (Access = private)
        
        function resetVals(app)
            if (app.MechanismDropDown.Value == "1")
                app.a1Edit.Value = 20;
                app.a2Edit.Value = 100;
            else
                app.a1Edit.Value = 20;
                app.a2Edit.Value = 75;
            end
            app.q0Edit.Value = 0;
            app.qfEdit.Value = 360;
            app.q_stepEdit.Value = 10;
            
            app.speed_units.Value = "1";
            app.acc_units.Value = "1";
            app.omegaEditField.Value = 10;
            app.alphaEditField.Value = 0;


            cla(app.p2_v_theta);
            cla(app.p2dot_v_theta);
            cla(app.p2ddot_v_theta);
            app.ExportP2dataButton.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetVals(app);
        end

        % Value changed function: MechanismDropDown
        function MechanismDropDownValueChanged(app, event)
            if (app.MechanismDropDown.Value == "1")
                app.a1Edit.Value = 20;
                app.a2Edit.Value = 100;
            else
                app.a1Edit.Value = 20;
                app.a2Edit.Value = 75;
            end
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            %Check units and set angle velocity/acceleration
            if (app.speed_units.Value == "2")
                qdot = deg2rad(app.omegaEditField.Value);
            else
                qdot = app.omegaEditField.Value;
            end
            if (app.acc_units.Value == "2")
                qddot = deg2rad(app.alphaEditField.Value);
            else
                qddot = app.alphaEditField.Value;
            end

            if (app.MechanismDropDown.Value == "1")
                [app.theta, app.p1, app.p2, app.p1dot, app.p2dot, app.p1ddot, app.p2ddot] = my_scotch_yoke(app.a1Edit.Value, app.a2Edit.Value, app.q0Edit.Value, app.qfEdit.Value, app.q_stepEdit.Value, qdot, qddot);
            else
                [app.theta, app.p1, app.p2, app.p1dot, app.p2dot, app.p1ddot, app.p2ddot] = my_slider_crank(app.a1Edit.Value, app.a2Edit.Value, app.q0Edit.Value, app.qfEdit.Value, app.q_stepEdit.Value, qdot, qddot);
            end
            
            plot(app.p2_v_theta, app.theta, app.p2); 
            xlim(app.p2_v_theta, [app.q0Edit.Value, app.qfEdit.Value]);
            %zoom(app.p2_v_theta, 'reset'); % Doesn't work in R2019

            plot(app.p2dot_v_theta, app.theta, app.p2dot); 
            xlim(app.p2dot_v_theta, [app.q0Edit.Value, app.qfEdit.Value]);
            %zoom(app.p2dot_v_theta, 'reset');

            plot(app.p2ddot_v_theta, app.theta, app.p2ddot);
            xlim(app.p2ddot_v_theta, [app.q0Edit.Value, app.qfEdit.Value]);
            %zoom(app.p2ddot_v_theta, 'reset');
            app.ExportP2dataButton.Enable = true;
        end

        % Button pushed function: ExportP2dataButton
        function ExportP2dataButtonPushed(app, event)
            try
                [file,path] = uiputfile('*.xlsx');
                filename = fullfile(path,file);
                Output = ["theta", "p2", "p2dot", "p2ddot"];
                Output = [Output; app.theta', app.p2', app.p2dot', app.p2ddot'];
                writematrix(Output,filename);
            catch
                disp("Ensure file folder has write permissions.");
                disp(Output)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.p2_v_theta = uiaxes(app.UIFigure);
            title(app.p2_v_theta, 'p2 vs theta')
            xlabel(app.p2_v_theta, 'theta (degrees)')
            ylabel(app.p2_v_theta, 'p2 (mm)')
            zlabel(app.p2_v_theta, 'Z')
            app.p2dot_v_theta = uiaxes(app.UIFigure);
            title(app.p2dot_v_theta, 'p2dot vs theta')
            xlabel(app.p2dot_v_theta, 'theta (degrees)')
            ylabel(app.p2dot_v_theta, 'p2dot (mm/s)')
            zlabel(app.p2dot_v_theta, 'Z')
            app.p2ddot_v_theta = uiaxes(app.UIFigure);
            title(app.p2ddot_v_theta, 'p2ddot vs theta')
            xlabel(app.p2ddot_v_theta, 'theta (degrees)')
            ylabel(app.p2ddot_v_theta, 'p2ddot (mm/s^2)')
            zlabel(app.p2ddot_v_theta, 'Z')
            app.MechanismDropDownLabel = uilabel(app.UIFigure);
            app.MechanismDropDownLabel.Text = 'Mechanism';
            app.MechanismDropDown = uidropdown(app.UIFigure);
            app.MechanismDropDown.Items = {'Scotch Yoke', 'Crank and Slider'};
            app.MechanismDropDown.ItemsData = {'1', '2'};
            app.MechanismDropDown.ValueChangedFcn = createCallbackFcn(app, @MechanismDropDownValueChanged, true);
            app.MechanismDropDown.Value = '1';
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1Edit = uieditfield(app.UIFigure, 'numeric');
            app.a1Edit.Value = 20;
            app.VariablesLabel = uilabel(app.UIFigure);
            app.VariablesLabel.Text = 'Variables';
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2Edit = uieditfield(app.UIFigure, 'numeric');
            app.a2Edit.Value = 100;
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.UIFigure);
            app.mmLabel_2.Text = 'mm';
            app.q0Edit = uieditfield(app.UIFigure, 'numeric');
            app.AnglerangeLabel = uilabel(app.UIFigure);
            app.AnglerangeLabel.Text = 'Angle range';
            app.qfEdit = uieditfield(app.UIFigure, 'numeric');
            app.qfEdit.Value = 360;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            app.a1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.a1EditFieldLabel_2.Text = 'Start';
            app.a1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.a1EditFieldLabel_3.Text = 'End';
            app.q_stepEdit = uieditfield(app.UIFigure, 'numeric');
            app.q_stepEdit.Value = 10;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '°';
            app.a1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.a1EditFieldLabel_4.Text = 'Step';
            app.aboutMe = uilabel(app.UIFigure);
            app.aboutMe.Text = 'Sam Wilcock 2022, s.wilcock@leeds.ac.uk';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.ExportP2dataButton = uibutton(app.UIFigure, 'push');
            app.ExportP2dataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportP2dataButtonPushed, true);
            app.ExportP2dataButton.Enable = 'off';
            app.ExportP2dataButton.Text = 'Export P2 data';
            app.omegaEditFieldLabel = uilabel(app.UIFigure);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.omegaEditField.Value = 10;
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.Editable = 'off';
            app.speed_units = uidropdown(app.UIFigure);
            app.speed_units.Items = {'rad/s', 'deg/s'};
            app.speed_units.ItemsData = {'1', '2'};
            app.speed_units.Value = '1';
            app.acc_units = uidropdown(app.UIFigure);
            app.acc_units.Items = {'rad/s^2', 'deg/s^2'};
            app.acc_units.ItemsData = {'1', '2'};
            app.acc_units.Value = '1';
            app.AngularmotionLabel = uilabel(app.UIFigure);
            app.AngularmotionLabel.Text = 'Angular motion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MechLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end